classdef app1d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CONTROLEDESPARAMETRESDACQUISITIONENRADIOLOGIECONVENTIONELLEMenu  matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        ContrledelexactitudedelatensionTab  matlab.ui.container.Tab
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        d90cmLabel_3               matlab.ui.control.Label
        mAs1Label_3                matlab.ui.control.Label
        appuyerButton_5            matlab.ui.control.Button
        ress2EditField             matlab.ui.control.EditField
        ress2EditFieldLabel        matlab.ui.control.Label
        latitude2EditField         matlab.ui.control.EditField
        kvmes2EditField            matlab.ui.control.EditField
        kvmes2EditFieldLabel       matlab.ui.control.Label
        kv2EditField               matlab.ui.control.EditField
        kv2EditFieldLabel          matlab.ui.control.Label
        ressEditField              matlab.ui.control.EditField
        ressEditFieldLabel         matlab.ui.control.Label
        appuyerButton_4            matlab.ui.control.Button
        kvmesEditField             matlab.ui.control.EditField
        kvmesEditFieldLabel        matlab.ui.control.Label
        kvEditField                matlab.ui.control.EditField
        kvEditFieldLabel           matlab.ui.control.Label
        latitudeEditField          matlab.ui.control.EditField
        ContrledelarptabilitdelatensionTab  matlab.ui.container.Tab
        Image3                     matlab.ui.control.Image
        d90cmLabel_2               matlab.ui.control.Label
        mAs1Label_2                matlab.ui.control.Label
        PourKV60KVLabel_2          matlab.ui.control.Label
        resultatEditField          matlab.ui.control.EditField
        resultatEditFieldLabel     matlab.ui.control.Label
        interpretationEditField    matlab.ui.control.EditField
        moyenne1EditField          matlab.ui.control.EditField
        moyenne1EditFieldLabel     matlab.ui.control.Label
        appuyerButton_3            matlab.ui.control.Button
        mesure_4EditField          matlab.ui.control.EditField
        mesure_4EditFieldLabel     matlab.ui.control.Label
        mesure_3EditField          matlab.ui.control.EditField
        mesure_3EditFieldLabel     matlab.ui.control.Label
        mesure_5EditField          matlab.ui.control.EditField
        mesure_5EditFieldLabel     matlab.ui.control.Label
        mesure_2EditField          matlab.ui.control.EditField
        mesure_2EditFieldLabel     matlab.ui.control.Label
        mesure_1EditField          matlab.ui.control.EditField
        mesure_1EditFieldLabel     matlab.ui.control.Label
        ContrledelalinaritdurayonnementdesortieTab  matlab.ui.container.Tab
        comEditField               matlab.ui.control.EditField
        comEditFieldLabel          matlab.ui.control.Label
        Buttongen                  matlab.ui.control.Button
        bouton5Button              matlab.ui.control.Button
        bouton4Button              matlab.ui.control.Button
        bouton3Button              matlab.ui.control.Button
        bouton2Button              matlab.ui.control.Button
        bouton1Button              matlab.ui.control.Button
        Image5                     matlab.ui.control.Image
        intr5EditField             matlab.ui.control.EditField
        intr4EditField             matlab.ui.control.EditField
        intr3EditField             matlab.ui.control.EditField
        intr2EditField             matlab.ui.control.EditField
        res5EditField_2            matlab.ui.control.EditField
        res5EditField_2Label       matlab.ui.control.Label
        res4EditField_2            matlab.ui.control.EditField
        res4EditField_2Label       matlab.ui.control.Label
        res3EditField_2            matlab.ui.control.EditField
        res3EditField_2Label       matlab.ui.control.Label
        res2EditField_2            matlab.ui.control.EditField
        res2EditField_2Label       matlab.ui.control.Label
        interpretationEditField_2  matlab.ui.control.EditField
        res1EditField_2            matlab.ui.control.EditField
        res1EditField_2Label       matlab.ui.control.Label
        appuyerButton              matlab.ui.control.Button
        kermamAs5EditField         matlab.ui.control.EditField
        kermamAs5EditFieldLabel    matlab.ui.control.Label
        kermamAs4EditField         matlab.ui.control.EditField
        kermamAs4EditFieldLabel    matlab.ui.control.Label
        kermamAs3EditField         matlab.ui.control.EditField
        kermamAs3EditFieldLabel    matlab.ui.control.Label
        kermamAs2EditField         matlab.ui.control.EditField
        kermamAs2EditFieldLabel    matlab.ui.control.Label
        kermamAs1EditField         matlab.ui.control.EditField
        kermamAs1EditFieldLabel    matlab.ui.control.Label
        kerma5EditField            matlab.ui.control.EditField
        kerma5EditFieldLabel       matlab.ui.control.Label
        kerma4EditField            matlab.ui.control.EditField
        kerma4EditFieldLabel       matlab.ui.control.Label
        kerma3EditField            matlab.ui.control.EditField
        kerma3EditFieldLabel       matlab.ui.control.Label
        kerma2EditField            matlab.ui.control.EditField
        kerma2EditFieldLabel       matlab.ui.control.Label
        kerma1EditField            matlab.ui.control.EditField
        kerma1EditFieldLabel       matlab.ui.control.Label
        moyenneEditField_2         matlab.ui.control.EditField
        moyenneEditField_2Label    matlab.ui.control.Label
        mAs5EditField              matlab.ui.control.EditField
        mAs5EditFieldLabel         matlab.ui.control.Label
        mAs4EditField              matlab.ui.control.EditField
        mAs4EditFieldLabel         matlab.ui.control.Label
        mAs3EditField              matlab.ui.control.EditField
        mAs3EditFieldLabel         matlab.ui.control.Label
        mAs2EditField              matlab.ui.control.EditField
        mAs2EditFieldLabel         matlab.ui.control.Label
        mAs1EditField              matlab.ui.control.EditField
        mAs1EditFieldLabel         matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: ContrledelexactitudedelatensionTab
        function ContrledelexactitudedelatensionTabButtonDown(app, event)
  
        end

        % Button pushed function: appuyerButton_4
        function appuyerButton_4Pushed(app, event)
                       % Get user inputs
            kvmes = str2double(app.kvmesEditField.Value);
            kv = str2double(app.kvEditField.Value);
            

            % Perform your calculations
            result = abs((kvmes - kv)/kvmes);
           

            % Display the result in the latitudeEditField
            app.ressEditField.Value = num2str(result);
            

            % Check the condition and update ressEditFieldLabel
            if result < 100
                app.latitudeEditField.Value='acepter';
            else
                app.latitudeEditField.Value= 'non acepter';
            end
        end

        % Value changed function: ressEditField
        function ressEditFieldValueChanged(app, event)
            kvmes2 = str2double(app.kvmesEditField.Value);
            kv2 = str2double(app.kvEditField.Value);
            
        end

        % Button pushed function: appuyerButton_5
        function appuyerButton_5Pushed(app, event)
            kvmes2 = str2double(app.kvmes2EditField.Value);
            kv2 = str2double(app.kv2EditField.Value);
            
            result2 = abs((kvmes2 - kv2)/kvmes2);
            app.ress2EditField.Value = num2str(result2);
            if result2 < 100
                app.latitude2EditField.Value='acepter';
            else
                app.latitude2EditField.Value= 'non acepter';
            end

        end

        % Button pushed function: appuyerButton_3
        function appuyerButton_3Pushed(app, event)
            mesure_1 = str2double(app.mesure_1EditField.Value );
            mesure_2= str2double (app.mesure_2EditField.Value);
            mesure_3= str2double (app.mesure_3EditField.Value);
            mesure_4= str2double (app.mesure_4EditField.Value);
            mesure_5= str2double  (app.mesure_5EditField.Value);

            moy1= (mesure_1+mesure_2+mesure_3+mesure_4+mesure_5)/5;


            app.moyenne1EditField.Value = num2str(moy1);
              k = min([mesure_1 , mesure_2 , mesure_3 , mesure_4 , mesure_5]);
               l= max([mesure_1  , mesure_2 , mesure_3 , mesure_4 , mesure_5]);
            
              res=(l-k)/moy1;
              app.resultatEditField.Value = num2str(res);
             
              if res<0.05
                  app.interpretationEditField.Value = 'acepter';
              else
                  app.interpretationEditField.Value = 'non acepter';
              end


        end

        % Callback function
        function appuyerButton_2Pushed(app, event)
           kermamesure1 = str2double(app.kermamesure1EditField.Value );
           kermamesure2= str2double (app.kermamesure2EditField.Value);
           kermamesure3= str2double (app.kermamesure3EditField.Value);
           kermamesure4= str2double (app.kermamesure4EditField.Value);
           kermamesure5= str2double  (app.kermamesure5EditField.Value);
           moye= (kermamesure1+kermamesure2+kermamesure3+kermamesure4+kermamesure5)/5;
           app.moyenneEditField_3.Value = num2str(moye);
           res1= abs((kermamesure1- moye)/moye);
           app.res1EditField.Value = num2str(res1);
              if res1<0.1
                  app. inter1EditField.Value = 'acepter';
              else
                  app.inter1EditField.Value = 'non acepter';
              end
             res2= abs((kermamesure2- moye)/moye);
             app.res2EditField.Value = num2str(res2);
              if res2<0.1
                  app. inter2EditField.Value = 'acepter';
              else
                  app.inter2EditField.Value = 'non acepter';
              end
              res3= abs((kermamesure3- moye)/moye);
              app.res3EditField.Value = num2str(res3);
              if res2<0.1
                  app. inter3EditField.Value = 'acepter';
              else
                  app.inter3EditField.Value = 'non acepter';
              end
                           res4= abs((kermamesure4- moye)/moye);
             app.res4EditField.Value = num2str(res4);
             if res4<0.1
                  app. inter4EditField.Value = 'acepter';
              else
                  app.inter4EditField.Value = 'non acepter';
             end
             res5= abs((kermamesure5- moye)/moye);
             app.res5EditField.Value = num2str(res5);
             if res5<0.1
                  app. inter5EditField.Value = 'acepter';
              else
                  app.inter5EditField.Value = 'non acepter';
              end


        end

        % Button pushed function: appuyerButton
        function appuyerButtonPushed(app, event)
           mas1 = str2double(app.mAs1EditField.Value );
            mas2= str2double (app.mAs2EditField.Value);
           mas3= str2double (app.mAs3EditField.Value);
          mas4= str2double (app.mAs4EditField.Value);
           mas5= str2double  (app.mAs5EditField.Value);
           kerma1 = str2double(app.kerma1EditField.Value );
           kerma2= str2double (app.kerma2EditField.Value);
           kerma3= str2double (app.kerma3EditField.Value);
           kerma4= str2double (app.kerma4EditField.Value);
           kerma5= str2double  (app.kerma5EditField.Value);
             

           r1= kerma1/mas1;
           app. kermamAs1EditField.Value = num2str(r1);
           r2= kerma2/mas2
           app. kermamAs2EditField.Value = num2str(r2);
           r3= kerma3/mas3
           app. kermamAs3EditField.Value = num2str(r3);
           r4= kerma4/mas4
           app. kermamAs4EditField.Value = num2str(r4);
           r5= kerma5/mas5
           app. kermamAs5EditField.Value = num2str(r5);
            
           moyen= (r1+r2+r3+r4+r5)/5;
           app.moyenneEditField_2.Value = num2str(moyen);

           acc=abs((r1-moyen)/moyen);
            app.res1EditField_2.Value = num2str(acc);
              if acc<=0.15
                  app.interpretationEditField_2.Value = 'acepter';
              else
                  app. interpretationEditField_2 .Value = 'non acepter';
              end
           

              acc2=abs((r2-moyen)/moyen);
            app.res2EditField_2 .Value = num2str(acc2);
              if acc2<=0.15
                  app.  intr2EditField .Value = 'acepter';
              else
                  app. intr2EditField .Value = 'non acepter';
              end


               acc3=abs((r3-moyen)/moyen);
            app.res3EditField_2 .Value = num2str(acc3);
              if acc3<=0.15
                  app.  intr3EditField .Value = 'acepter';
              else
                  app. intr3EditField .Value = 'non acepter';
              end


               acc4=abs((r4-moyen)/moyen);
            app.res4EditField_2 .Value = num2str(acc4);
              if acc4<=0.15
                  app.  intr4EditField .Value = 'acepter';
              else
                  app. intr4EditField .Value = 'non acepter';
              end


               acc5=abs((r5-moyen)/moyen);
            app.res5EditField_2 .Value = num2str(acc5);
              if acc5<=0.15
                  app.  intr5EditField .Value = 'acepter';
              else
                  app. intr5EditField .Value = 'non acepter';
              end


    % Extract mAs and kerma values
    mAsValues = [mas1, mas2, mas3, mas4, mas5];
    kermaValues = [kerma1, kerma2, kerma3, kerma4, kerma5];

    % Print the values for debugging
    disp('mAs Values:');
    disp(mAsValues);
    disp('Kerma Values:');
    disp(kermaValues);

    % Plot the curve
    plot(app.UIAxes, mAsValues, kermaValues, 'o-', 'LineWidth', 2);
    title(app.UIAxes, 'Kerma vs. mAs');
    xlabel(app.UIAxes, 'mAs');
    ylabel(app.UIAxes, 'Kerma');

    % Print a message to verify that the function is executed
    disp('Plotting executed.');

    % Your existing code...


        end

        % Callback function
        function conButtonPushed(app, event)
             
    % app.TabGroup.SelectedTab = app.ContrledelalinaritdurayonnementdesortieTab
        % Toggle the visibility of the uitabgroup
    if strcmp(app.TabGroup.Visible, 'on')
        app.TabGroup.Visible = 'off';
    else
        app.TabGroup.Visible = 'on';
    end

    
    % Set the Visible property of other tabs to 'off' if needed
    % app.OtherTab1.Visible = 'off';
    % app.OtherTab2.Visible = 'off';

        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
                 % Ajouter un axe pour l'image de fond
    app.UIAxes = uiaxes(app.UIFigure);
    app.UIAxes.Position = [0 0 1 1];  % Positionner l'axe sur toute la figure
    app.UIAxes.Visible = 'off';  % Rendre l'axe invisible

    % Charger l'image et l'afficher dans l'axe
    backgroundImage = imread('/MATLAB Drive/o.png');
    image(app.UIAxes, backgroundImage);
    
        end

        % Button pushed function: bouton1Button
        function bouton1ButtonPushed(app, event)
           mas1 = str2double(app.mAs1EditField.Value );
            mas2= str2double (app.mAs2EditField.Value);
           mas3= str2double (app.mAs3EditField.Value);
          mas4= str2double (app.mAs4EditField.Value);
           mas5= str2double  (app.mAs5EditField.Value);
           kerma1 = str2double(app.kerma1EditField.Value );
           kerma2= str2double (app.kerma2EditField.Value);
           kerma3= str2double (app.kerma3EditField.Value);
           kerma4= str2double (app.kerma4EditField.Value);
           kerma5= str2double  (app.kerma5EditField.Value);
           r1= kerma1/mas1;
           app. kermamAs1EditField.Value = num2str(r1);
                      r2= kerma2/mas2
           
           r3= kerma3/mas3
           
           r4= kerma4/mas4
           
           r5= kerma5/mas5
          
            
           moyen= (r1+r2+r3+r4+r5)/5;
           app.moyenneEditField_2.Value = num2str(moyen);

           acc=abs((r1-moyen)/moyen);
            app.res1EditField_2.Value = num2str(acc);
              if acc<=0.15
                  app.interpretationEditField_2.Value = 'acepter';
              else
                  app. interpretationEditField_2 .Value = 'non acepter';
              end

           
        end

        % Button pushed function: bouton2Button
        function bouton2ButtonPushed(app, event)
             mas1 = str2double(app.mAs1EditField.Value );
            mas2= str2double (app.mAs2EditField.Value);
           mas3= str2double (app.mAs3EditField.Value);
          mas4= str2double (app.mAs4EditField.Value);
           mas5= str2double  (app.mAs5EditField.Value);
           kerma1 = str2double(app.kerma1EditField.Value );
           kerma2= str2double (app.kerma2EditField.Value);
           kerma3= str2double (app.kerma3EditField.Value);
           kerma4= str2double (app.kerma4EditField.Value);
           kerma5= str2double  (app.kerma5EditField.Value);
                      r2= kerma2/mas2;
           app. kermamAs2EditField.Value = num2str(r2);
                      r3= kerma3/mas3;
           
           r4= kerma4/mas4;
           
           r5= kerma5/mas5;
           r1= kerma1/mas1;

           moyen= (r1+r2+r3+r4+r5)/5;
           app.moyenneEditField_2.Value = num2str(moyen);
               acc2=abs((r2-moyen)/moyen);
            app.res2EditField_2 .Value = num2str(acc2);
              if acc2<=0.15
                  app.  intr2EditField .Value = 'acepter';
              else
                  app. intr2EditField .Value = 'non acepter';
              end


        end

        % Button pushed function: bouton3Button
        function bouton3ButtonPushed(app, event)
              mas1 = str2double(app.mAs1EditField.Value );
            mas2= str2double (app.mAs2EditField.Value);
           mas3= str2double (app.mAs3EditField.Value);
          mas4= str2double (app.mAs4EditField.Value);
           mas5= str2double  (app.mAs5EditField.Value);
           kerma1 = str2double(app.kerma1EditField.Value );
           kerma2= str2double (app.kerma2EditField.Value);
           kerma3= str2double (app.kerma3EditField.Value);
           kerma4= str2double (app.kerma4EditField.Value);
           kerma5= str2double  (app.kerma5EditField.Value);
           r4= kerma4/mas4;
           
           r5= kerma5/mas5;
           r1= kerma1/mas1;
           r2= kerma2/mas2;
           r3= kerma3/mas3;
           app. kermamAs3EditField.Value = num2str(r3);

            moyen= (r1+r2+r3+r4+r5)/5;
           app.moyenneEditField_2.Value = num2str(moyen);

             acc3=abs((r3-moyen)/moyen);
            app.res3EditField_2 .Value = num2str(acc3);
              if acc3<=0.15
                  app.  intr3EditField .Value = 'acepter';
              else
                  app. intr3EditField .Value = 'non acepter';
              end


        end

        % Button pushed function: bouton4Button
        function bouton4ButtonPushed(app, event)
                          mas1 = str2double(app.mAs1EditField.Value );
            mas2= str2double (app.mAs2EditField.Value);
           mas3= str2double (app.mAs3EditField.Value);
          mas4= str2double (app.mAs4EditField.Value);
           mas5= str2double  (app.mAs5EditField.Value);
           kerma1 = str2double(app.kerma1EditField.Value );
           kerma2= str2double (app.kerma2EditField.Value);
           kerma3= str2double (app.kerma3EditField.Value);
           kerma4= str2double (app.kerma4EditField.Value);
           kerma5= str2double  (app.kerma5EditField.Value);
           r4= kerma4/mas4;
           app. kermamAs4EditField.Value = num2str(r4);
           
           r5= kerma5/mas5;
           r1= kerma1/mas1;
           r2= kerma2/mas2;
           r3= kerma3/mas3;
           

            moyen= (r1+r2+r3+r4+r5)/5;
           app.moyenneEditField_2.Value = num2str(moyen);
             acc4=abs((r4-moyen)/moyen);
            app.res4EditField_2 .Value = num2str(acc4);
              if acc4<=0.15
                  app.  intr4EditField .Value = 'acepter';
              else
                  app. intr4EditField .Value = 'non acepter';
              end
        end

        % Button pushed function: bouton5Button
        function bouton5ButtonPushed(app, event)
                                     mas1 = str2double(app.mAs1EditField.Value );
            mas2= str2double (app.mAs2EditField.Value);
           mas3= str2double (app.mAs3EditField.Value);
          mas4= str2double (app.mAs4EditField.Value);
           mas5= str2double  (app.mAs5EditField.Value);
           kerma1 = str2double(app.kerma1EditField.Value );
           kerma2= str2double (app.kerma2EditField.Value);
           kerma3= str2double (app.kerma3EditField.Value);
           kerma4= str2double (app.kerma4EditField.Value);
           kerma5= str2double  (app.kerma5EditField.Value);
           r4= kerma4/mas4;
          
           
           r5= kerma5/mas5;
            app. kermamAs5EditField.Value = num2str(r5);
           r1= kerma1/mas1;
           r2= kerma2/mas2;
           r3= kerma3/mas3;
           

            moyen= (r1+r2+r3+r4+r5)/5;
           app.moyenneEditField_2.Value = num2str(moyen);
             acc5=abs((r5-moyen)/moyen);
            app.res5EditField_2 .Value = num2str(acc5);
              if acc5<=0.15
                  app.  intr5EditField .Value = 'acepter';
              else
                  app. intr5EditField .Value = 'non acepter';
              end
        end

        % Button pushed function: Buttongen
        function ButtongenPushed(app, event)

    if strcmp(app.intr5EditField.Value, 'non acepter')
        app.comEditField.Value = 'non acepter';
    elseif strcmp(app.intr4EditField.Value, 'non acepter')
        app.comEditField.Value = 'non acepter';
    elseif strcmp(app.intr3EditField.Value, 'non acepter')
        app.comEditField.Value = 'non acepter';
    elseif strcmp(app.intr2EditField.Value, 'non acepter')
        app.comEditField.Value = 'non acepter';
    elseif strcmp(app.interpretationEditField_2.Value, 'non acepter')
        app.comEditField.Value = 'non acepter';
    else
        app.comEditField.Value = 'acepter';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CONTROLEDESPARAMETRESDACQUISITIONENRADIOLOGIECONVENTIONELLEMenu = uimenu(app.UIFigure);
            app.CONTROLEDESPARAMETRESDACQUISITIONENRADIOLOGIECONVENTIONELLEMenu.Text = 'CONTROLE DES PARAMETRES D''ACQUISITION EN RADIOLOGIE CONVENTIONELLE';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ContrledelexactitudedelatensionTab = uitab(app.TabGroup);
            app.ContrledelexactitudedelatensionTab.Title = 'Contrôle de l’exactitude de la tension';
            app.ContrledelexactitudedelatensionTab.ButtonDownFcn = createCallbackFcn(app, @ContrledelexactitudedelatensionTabButtonDown, true);
            app.latitudeEditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.kvEditFieldLabel = uilabel(app.ContrledelexactitudedelatensionTab);
            app.kvEditFieldLabel.Text = 'kv';
            app.kvEditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.kvEditField.Value = '60';
            app.kvmesEditFieldLabel = uilabel(app.ContrledelexactitudedelatensionTab);
            app.kvmesEditFieldLabel.Text = 'kvmes';
            app.kvmesEditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.appuyerButton_4 = uibutton(app.ContrledelexactitudedelatensionTab, 'push');
            app.appuyerButton_4.ButtonPushedFcn = createCallbackFcn(app, @appuyerButton_4Pushed, true);
            app.appuyerButton_4.Text = 'appuyer';
            app.ressEditFieldLabel = uilabel(app.ContrledelexactitudedelatensionTab);
            app.ressEditFieldLabel.Text = 'ress';
            app.ressEditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.ressEditField.ValueChangedFcn = createCallbackFcn(app, @ressEditFieldValueChanged, true);
            app.kv2EditFieldLabel = uilabel(app.ContrledelexactitudedelatensionTab);
            app.kv2EditFieldLabel.Text = 'kv2';
            app.kv2EditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.kv2EditField.Value = '80';
            app.kvmes2EditFieldLabel = uilabel(app.ContrledelexactitudedelatensionTab);
            app.kvmes2EditFieldLabel.Text = 'kvmes2';
            app.kvmes2EditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.latitude2EditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.ress2EditFieldLabel = uilabel(app.ContrledelexactitudedelatensionTab);
            app.ress2EditFieldLabel.Text = 'ress2';
            app.ress2EditField = uieditfield(app.ContrledelexactitudedelatensionTab, 'text');
            app.appuyerButton_5 = uibutton(app.ContrledelexactitudedelatensionTab, 'push');
            app.appuyerButton_5.ButtonPushedFcn = createCallbackFcn(app, @appuyerButton_5Pushed, true);
            app.appuyerButton_5.Text = 'appuyer';
            app.mAs1Label_3 = uilabel(app.ContrledelexactitudedelatensionTab);
            app.mAs1Label_3.Text = 'mAs=1';
            app.d90cmLabel_3 = uilabel(app.ContrledelexactitudedelatensionTab);
            app.d90cmLabel_3.Text = 'd=90 cm';
            app.Image = uiimage(app.ContrledelexactitudedelatensionTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot_1.png');
            app.Image2 = uiimage(app.ContrledelexactitudedelatensionTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot_1.png');
            app.ContrledelarptabilitdelatensionTab = uitab(app.TabGroup);
            app.ContrledelarptabilitdelatensionTab.Title = 'Contrôle de la répétabilité de la tension';
            app.mesure_1EditFieldLabel = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.mesure_1EditFieldLabel.Text = 'mesure_1';
            app.mesure_1EditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.mesure_2EditFieldLabel = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.mesure_2EditFieldLabel.Text = 'mesure_2';
            app.mesure_2EditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.mesure_5EditFieldLabel = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.mesure_5EditFieldLabel.Text = 'mesure_5';
            app.mesure_5EditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.mesure_3EditFieldLabel = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.mesure_3EditFieldLabel.Text = 'mesure_3';
            app.mesure_3EditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.mesure_4EditFieldLabel = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.mesure_4EditFieldLabel.Text = 'mesure_4';
            app.mesure_4EditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.appuyerButton_3 = uibutton(app.ContrledelarptabilitdelatensionTab, 'push');
            app.appuyerButton_3.ButtonPushedFcn = createCallbackFcn(app, @appuyerButton_3Pushed, true);
            app.appuyerButton_3.Text = 'appuyer';
            app.moyenne1EditFieldLabel = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.moyenne1EditFieldLabel.Text = 'moyenne1';
            app.moyenne1EditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.interpretationEditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.resultatEditFieldLabel = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.resultatEditFieldLabel.Text = 'resultat';
            app.resultatEditField = uieditfield(app.ContrledelarptabilitdelatensionTab, 'text');
            app.PourKV60KVLabel_2 = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.PourKV60KVLabel_2.Text = 'Pour KV= 60 KV';
            app.mAs1Label_2 = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.mAs1Label_2.Text = 'mAs=1';
            app.d90cmLabel_2 = uilabel(app.ContrledelarptabilitdelatensionTab);
            app.d90cmLabel_2.Text = 'd=90cm';
            app.Image3 = uiimage(app.ContrledelarptabilitdelatensionTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Screenshot_1.png');
            app.ContrledelalinaritdurayonnementdesortieTab = uitab(app.TabGroup);
            app.ContrledelalinaritdurayonnementdesortieTab.Title = 'Contrôle de la linéarité du rayonnement de sortie';
            app.UIAxes = uiaxes(app.ContrledelalinaritdurayonnementdesortieTab);
            title(app.UIAxes, 'kerma mesure en fonction des mAs')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.mAs1EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.mAs1EditFieldLabel.Text = 'mAs1';
            app.mAs1EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.mAs2EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.mAs2EditFieldLabel.Text = 'mAs 2';
            app.mAs2EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.mAs3EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.mAs3EditFieldLabel.Text = 'mAs 3';
            app.mAs3EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.mAs4EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.mAs4EditFieldLabel.Text = 'mAs 4';
            app.mAs4EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.mAs5EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.mAs5EditFieldLabel.Text = 'mAs 5';
            app.mAs5EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.moyenneEditField_2Label = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.moyenneEditField_2Label.Text = 'moyenne';
            app.moyenneEditField_2 = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kerma1EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kerma1EditFieldLabel.Text = 'kerma1';
            app.kerma1EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kerma2EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kerma2EditFieldLabel.Text = 'kerma2';
            app.kerma2EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kerma3EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kerma3EditFieldLabel.Text = 'kerma3';
            app.kerma3EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kerma4EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kerma4EditFieldLabel.Text = 'kerma4';
            app.kerma4EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kerma5EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kerma5EditFieldLabel.Text = 'kerma5';
            app.kerma5EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kermamAs1EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kermamAs1EditFieldLabel.Text = 'kerma/mAs1';
            app.kermamAs1EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kermamAs2EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kermamAs2EditFieldLabel.Text = 'kerma/mAs2';
            app.kermamAs2EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kermamAs3EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kermamAs3EditFieldLabel.Text = 'kerma/mAs3';
            app.kermamAs3EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kermamAs4EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kermamAs4EditFieldLabel.Text = 'kerma/mAs4';
            app.kermamAs4EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.kermamAs5EditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.kermamAs5EditFieldLabel.Text = 'kerma/mAs5';
            app.kermamAs5EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.appuyerButton = uibutton(app.ContrledelalinaritdurayonnementdesortieTab, 'push');
            app.appuyerButton.ButtonPushedFcn = createCallbackFcn(app, @appuyerButtonPushed, true);
            app.appuyerButton.Text = 'appuyer';
            app.res1EditField_2Label = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.res1EditField_2Label.Text = 'res1';
            app.res1EditField_2 = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.interpretationEditField_2 = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.res2EditField_2Label = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.res2EditField_2Label.Text = 'res2';
            app.res2EditField_2 = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.res3EditField_2Label = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.res3EditField_2Label.Text = 'res3';
            app.res3EditField_2 = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.res4EditField_2Label = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.res4EditField_2Label.Text = 'res4';
            app.res4EditField_2 = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.res5EditField_2Label = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.res5EditField_2Label.Text = 'res5';
            app.res5EditField_2 = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.intr2EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.intr3EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.intr4EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.intr5EditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            app.Image5 = uiimage(app.ContrledelalinaritdurayonnementdesortieTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Screenshot_1.png');
            app.bouton1Button = uibutton(app.ContrledelalinaritdurayonnementdesortieTab, 'push');
            app.bouton1Button.ButtonPushedFcn = createCallbackFcn(app, @bouton1ButtonPushed, true);
            app.bouton1Button.Text = 'bouton1';
            app.bouton2Button = uibutton(app.ContrledelalinaritdurayonnementdesortieTab, 'push');
            app.bouton2Button.ButtonPushedFcn = createCallbackFcn(app, @bouton2ButtonPushed, true);
            app.bouton2Button.Text = 'bouton2';
            app.bouton3Button = uibutton(app.ContrledelalinaritdurayonnementdesortieTab, 'push');
            app.bouton3Button.ButtonPushedFcn = createCallbackFcn(app, @bouton3ButtonPushed, true);
            app.bouton3Button.Text = 'bouton3';
            app.bouton4Button = uibutton(app.ContrledelalinaritdurayonnementdesortieTab, 'push');
            app.bouton4Button.ButtonPushedFcn = createCallbackFcn(app, @bouton4ButtonPushed, true);
            app.bouton4Button.Text = 'bouton4';
            app.bouton5Button = uibutton(app.ContrledelalinaritdurayonnementdesortieTab, 'push');
            app.bouton5Button.ButtonPushedFcn = createCallbackFcn(app, @bouton5ButtonPushed, true);
            app.bouton5Button.Text = 'bouton5';
            app.Buttongen = uibutton(app.ContrledelalinaritdurayonnementdesortieTab, 'push');
            app.Buttongen.ButtonPushedFcn = createCallbackFcn(app, @ButtongenPushed, true);
            app.Buttongen.Text = 'Buttongen';
            app.comEditFieldLabel = uilabel(app.ContrledelalinaritdurayonnementdesortieTab);
            app.comEditFieldLabel.Text = 'com';
            app.comEditField = uieditfield(app.ContrledelalinaritdurayonnementdesortieTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end